classdef PhysicalExperiment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        dEditField           matlab.ui.control.NumericEditField
        dEditFieldLabel      matlab.ui.control.Label
        AEditField           matlab.ui.control.NumericEditField
        AEditFieldLabel      matlab.ui.control.Label
        E0EditField          matlab.ui.control.NumericEditField
        E0EditFieldLabel     matlab.ui.control.Label
        Lamp                 matlab.ui.control.Lamp
        Switch               matlab.ui.control.Switch
        frequencyGauge       matlab.ui.control.SemicircularGauge
        frequencyGaugeLabel  matlab.ui.control.Label
        OperateButton        matlab.ui.control.Button
        frequencyKnob        matlab.ui.control.Knob
        frequencyKnobLabel   matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        Toolbar              matlab.ui.container.Toolbar
        PushTool             matlab.ui.container.toolbar.PushTool
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if strcmp(value,"On")
                app.Lamp.Color = 'g';
            end
            if strcmp(value,"Off")
                app.Lamp.Color = 'r'; 
            end
        end

        % Button pushed function: OperateButton
        function OperateButtonPushed(app, event)
       
           E1 = app.E0EditField.Value;
           E2 = 0.7*E1;
           E3 = 0.3*E1;
           w = 2*pi*app.frequencyKnob.Value;
           A = 0.00001*app.AEditField.Value;
           d = 0.00001*app.dEditField.Value;
           x=-0.1:0.001:0.1;
           y1=E1*E2*cos((2*pi*A/d)*cos(w*x));
           y2=E1*E2*cos((2*pi*A/d)*cos(w*x))+E2*E3*cos((2*pi*A/d)*cos(w*x))+E3*E1*cos((2*pi*A/d)*cos(w*x));
           plot(app.UIAxes,x,y1);
           plot(app.UIAxes2,x,y2);
            
        end

        % Value changed function: frequencyKnob
        function frequencyKnobValueChanged(app, event)
       
                 value = app.frequencyKnob.Value;
                 app.frequencyGauge.Value=value;
           
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {337, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.frequencyKnobLabel = uilabel(app.LeftPanel);
            app.frequencyKnobLabel.Text = 'frequency';
            app.frequencyKnob = uiknob(app.LeftPanel, 'continuous');
            app.frequencyKnob.Limits = [200 800];
            app.frequencyKnob.ValueChangedFcn = createCallbackFcn(app, @frequencyKnobValueChanged, true);
            app.frequencyKnob.Value = 500;
            app.OperateButton = uibutton(app.LeftPanel, 'push');
            app.OperateButton.ButtonPushedFcn = createCallbackFcn(app, @OperateButtonPushed, true);
            app.OperateButton.Text = 'Operate';
            app.frequencyGaugeLabel = uilabel(app.LeftPanel);
            app.frequencyGaugeLabel.Text = 'frequency';
            app.frequencyGauge = uigauge(app.LeftPanel, 'semicircular');
            app.frequencyGauge.Limits = [200 800];
            app.frequencyGauge.MajorTicks = [200 400 600 800];
            app.frequencyGauge.MajorTickLabels = {'200', '400', '600', '800'};
            app.frequencyGauge.Value = 100;
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.LeftPanel);
            app.E0EditFieldLabel = uilabel(app.LeftPanel);
            app.E0EditFieldLabel.Text = 'E0';
            app.E0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.AEditFieldLabel = uilabel(app.LeftPanel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dEditFieldLabel = uilabel(app.LeftPanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'precise dipaly window')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'display window')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhysicalExperiment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end